classdef MagnetPatternRecognitionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataMenu                        matlab.ui.container.Menu
        SavemagDataMenu                 matlab.ui.container.Menu
        SavepatRecMenu                  matlab.ui.container.Menu
        SavepatRectxtMenu               matlab.ui.container.Menu
        LoadpatRecMenu                  matlab.ui.container.Menu
        MessageLabel                    matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        TrainingandtestingTab           matlab.ui.container.Tab
        ClassifierperformancePanel      matlab.ui.container.Panel
        AverageaccuracyEditField        matlab.ui.control.NumericEditField
        AverageaccuracyLabel            matlab.ui.control.Label
        PerformanceTable                matlab.ui.control.Table
        ClassifierparametersPanel       matlab.ui.container.Panel
        BottlenecktrialsEditField       matlab.ui.control.NumericEditField
        BottlenecktrialsEditFieldLabel  matlab.ui.control.Label
        PercentageofscoreEditField      matlab.ui.control.NumericEditField
        PercentageofscoreEditFieldLabel  matlab.ui.control.Label
        FeatureSelectionDropDown        matlab.ui.control.DropDown
        FeatureSelectionDropDownLabel   matlab.ui.control.Label
        RandomizedataCheckBox           matlab.ui.control.CheckBox
        PlotadditionalfiguresCheckBox   matlab.ui.control.CheckBox
        TransientDataCheckBox           matlab.ui.control.CheckBox
        PlotconfusionmatrixCheckBox     matlab.ui.control.CheckBox
        NumRepTestEditField             matlab.ui.control.NumericEditField
        RepetitionsusedfortestingLabel  matlab.ui.control.Label
        TrainingDataPercentageEditField  matlab.ui.control.NumericEditField
        PercentageofdatatobeusedfortrainingEditFieldLabel  matlab.ui.control.Label
        TopologyDropDown                matlab.ui.control.DropDown
        TopologyDropDownLabel           matlab.ui.control.Label
        NormalizationDropDown           matlab.ui.control.DropDown
        NormalizationDropDownLabel      matlab.ui.control.Label
        KernelDropDown                  matlab.ui.control.DropDown
        KernelDropDownLabel             matlab.ui.control.Label
        ClassifierDropDown              matlab.ui.control.DropDown
        ClassifierDropDownLabel         matlab.ui.control.Label
        RunOfflineTrainingButton        matlab.ui.control.Button
        AvailablemovementsandextractedfeaturesPanel  matlab.ui.container.Panel
        AvailableMagnetFeaturesListBox  matlab.ui.control.ListBox
        AvailableMagnetFeaturesListBoxLabel  matlab.ui.control.Label
        AvailablemagnetpairsListBox     matlab.ui.control.ListBox
        AvailablemagnetpairsLabel       matlab.ui.control.Label
        AvailableMovementsListBox       matlab.ui.control.ListBox
        AvailablemovementsLabel         matlab.ui.control.Label
        ExtractedFeaturesListBox        matlab.ui.control.ListBox
        ExtractedFeaturesLabel          matlab.ui.control.Label
        CreatingdatastructureTab        matlab.ui.container.Tab
        InformationaboutthefilePanel    matlab.ui.container.Panel
        LoadrestdataButton              matlab.ui.control.Button
        MovementstobeloadedListBox      matlab.ui.control.ListBox
        MovementstobeloadedListBoxLabel  matlab.ui.control.Label
        NumOfMagnetsEditField           matlab.ui.control.NumericEditField
        NumbermagnetsLabel              matlab.ui.control.Label
        LoadtxtfilesfromafolderButton   matlab.ui.control.Button
        LabelCollumnEditField           matlab.ui.control.NumericEditField
        CollumnwhichcontainslabelLabel  matlab.ui.control.Label
        RestTimeEditField               matlab.ui.control.NumericEditField
        ContractiontimeLabel_2          matlab.ui.control.Label
        ContractionTimeEditField        matlab.ui.control.NumericEditField
        ContractiontimeLabel            matlab.ui.control.Label
        DateofacquisitionEditField      matlab.ui.control.EditField
        DateofacquisitionEditFieldLabel  matlab.ui.control.Label
        NumOfRepEditField               matlab.ui.control.NumericEditField
        NumberofrepetitionsEditFieldLabel  matlab.ui.control.Label
        NumOfCollumsEditField           matlab.ui.control.NumericEditField
        NumberofcollumnswhichcontaindataEditFieldLabel  matlab.ui.control.Label
        ExtractingFeaturesTab           matlab.ui.container.Tab
        MagnetfeaturesPanel             matlab.ui.container.Panel
        UsedisplacementCheckBox         matlab.ui.control.CheckBox
        ExctractFeaturesButton          matlab.ui.control.Button
        EffectivecontractioncTpEditField  matlab.ui.control.NumericEditField
        EffectivecontractioncTpEditFieldLabel  matlab.ui.control.Label
        WindowincremensmsEditField      matlab.ui.control.NumericEditField
        WindowincremensmsEditFieldLabel  matlab.ui.control.Label
        TimewindowmsEditField           matlab.ui.control.NumericEditField
        TimewindowmsEditFieldLabel      matlab.ui.control.Label
        SamplefrequencyEditField        matlab.ui.control.NumericEditField
        SamplefrequencyEditFieldLabel   matlab.ui.control.Label
        UsejustsamplesCheckBox          matlab.ui.control.CheckBox
        AddRestasmovementCheckBox       matlab.ui.control.CheckBox
        FeaturesListBox                 matlab.ui.control.ListBox
        FeaturesListBoxLabel            matlab.ui.control.Label
        AngulardisplacementCheckBox     matlab.ui.control.CheckBox
        LineardisplacementCheckBox      matlab.ui.control.CheckBox
        IncasemagDataisalreadysavedLabel  matlab.ui.control.Label
        MagnetdatainfoPanel             matlab.ui.container.Panel
        MovementsListBox                matlab.ui.control.ListBox
        MovementsListBoxLabel           matlab.ui.control.Label
        NumberofmagnetsEditField        matlab.ui.control.NumericEditField
        NumberofmagnetsEditFieldLabel   matlab.ui.control.Label
        ResttimeEditField               matlab.ui.control.NumericEditField
        ResttimeEditFieldLabel          matlab.ui.control.Label
        ContractiontimeEditField        matlab.ui.control.NumericEditField
        ContractiontimeEditFieldLabel   matlab.ui.control.Label
        NumberofmovementsEditField      matlab.ui.control.NumericEditField
        NumberofmovementsEditFieldLabel  matlab.ui.control.Label
        NumberofchannelsEditField       matlab.ui.control.NumericEditField
        NumberofchannelsEditFieldLabel  matlab.ui.control.Label
        NumberofrepetitionsEditField    matlab.ui.control.NumericEditField
        NumberofrepetitionsEditFieldLabel_2  matlab.ui.control.Label
        DisplaymagDatainfoButton        matlab.ui.control.Button
        LoadmagDataButton               matlab.ui.control.Button
        DataVisualizationTab            matlab.ui.container.Tab
        DataFeatureVisualizationPanel   matlab.ui.container.Panel
        AlgorithmforFRDropDown          matlab.ui.control.DropDown
        AlgorithmforFRDropDownLabel     matlab.ui.control.Label
        Plot3DButton                    matlab.ui.control.Button
        NRVarianceEditField             matlab.ui.control.NumericEditField
        NumberofrepetitionsEditField_2Label  matlab.ui.control.Label
        CheckchannelvarianceButton      matlab.ui.control.Button
        FeaturesPlotListBox             matlab.ui.control.ListBox
        FeaturesListBox_2Label          matlab.ui.control.Label
        AngulardistPlotCheckBox         matlab.ui.control.CheckBox
        LineardistPlotCheckBox          matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        DatabalancePanel                matlab.ui.container.Panel
        BalancedataButton               matlab.ui.control.Button
        DataBalanceTable                matlab.ui.control.Table
        CheckDataBalanceButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        txtData   % .txt file which contains the data (samples in rows, channels in collumns)
        magData   % a structure that contains the magnet localizations and all of the info about the acquisition
        patRecMag % a structure which contains the classifier information for magnet data
    end
    
    methods (Access = private)
        
        function CreateMagData(app) % Creating a strucutre (magData) which contains all the info about the acquisition
            app.magData = [];
            app.magData.nM = length(app.MovementstobeloadedListBox.Value); % number of movements
            app.magData.mov = app.MovementstobeloadedListBox.Value'; % movement names
            app.magData.nR = app.NumOfRepEditField.Value; % number of repetitions
            app.magData.cT = app.ContractionTimeEditField.Value; % contraction time
            app.magData.rT = app.RestTimeEditField.Value; % rest time
            app.magData.date = app.DateofacquisitionEditField.Value; % date of acquisition
            app.magData.nCh = app.NumOfCollumsEditField.Value; % number of channels
            app.magData.nMag = app.NumOfMagnetsEditField.Value; % number of magnets
            app.magData.rawData = cell(1,app.magData.nM);
            app.magData.label = cell(1,app.magData.nM);
            for i = 1:app.magData.nM
                app.magData.rawData{i} = app.txtData{i}(:,1:app.magData.nCh); % acquired data
                app.magData.label{i} = app.txtData{i}(:,app.LabelCollumnEditField.Value); % labels
            end
        end
        
        function error = CheckMessage(app, message)
            app.MessageLabel.Text = message;
            if contains(message,'Error')
                disp(app.magData);
                error = 1;
            else
                error = 0;
            end
        end

        function sortedList = SortListBox(app,listBox)
            value = listBox.Value;
            [~, idx] = ismember(value,listBox.Items);
            idx = sort(idx);
            sortedList = listBox.Items(idx);
        end
        
        function ch = GetChannels(app,flagDist) % Gives back channel indexes depending on which distance you want to extract
            % flagDist is 1 for linear distance, and 0 for angular
            if (isempty(app.magData) || ~isfield(app.magData,'dataLA'))
                app.MessageLabel.Text = 'Error: no data available!';
                return;
            end
            % Linear distance ----------------------------------
            if (flagDist)
                if (app.magData.magSelFeatures(1))
                    if(app.magData.magSelFeatures(2))
                        ch = 1:2:(app.magData.nCh*2);
                    else
                        ch = 1:app.magData.nCh;
                    end
                else
                    app.MessageLabel.Text = 'Error: Linear distance not extracted!';
                    return;
                end
            % Angular distance ---------------------------------
            else
                if (app.magData.magSelFeatures(2))
                    if(app.magData.magSelFeatures(1))
                        ch = 2:2:(app.magData.nCh*2);
                    else
                        ch = 1:app.magData.nCh;
                    end
                else
                    app.MessageLabel.Text = 'Error: Angular distance not extracted!';
                    return;
                end
            end
        end
        
        function CalculateRestDistance(app)
            restData = app.magData.restData;
            mDim = 6;
            mPairs = app.patRecMag.magnetPairs;
            nPairs = size(mPairs,1);
            normMeanRestDist = zeros(1,nPairs);
            normMeanRestAng = zeros(1,nPairs);
            for i = 1:nPairs
                distR = restData(:,(1:3)+mDim*(mPairs(i,1)-1)) - restData(:,(1:3)+mDim*(mPairs(i,2)-1)); 
                angR  = atan2(vecnorm(cross(restData(:,(4:6)+mDim*(mPairs(i,1)-1)),restData(:,(4:6)+mDim*(mPairs(i,2)-1)),2),2,2),dot(restData(:,(4:6)+mDim*(mPairs(i,1)-1)),restData(:,(4:6)+mDim*(mPairs(i,2)-1)),2)); % rest angular distance
                meanRestDist = median(distR,1);
                meanRestAng = median(angR,1);
                normMeanRestDist(i) = vecnorm(meanRestDist); % median rest linear distance
                normMeanRestAng(i) = vecnorm(meanRestAng);
            end
            if(sum(app.patRecMag.magSelFeatures) == 2)
                restDistances = zeros(1,nPairs*2);
                restDistances(1:2:end) = normMeanRestDist;
                restDistances(2:2:end) = normMeanRestAng;
            else
                if(app.patRecMag.magSelFeatures(1))
                    restDistances = normMeanRestDist;
                else
                    restDistances = normMeanRestAng;
                end
            end
            app.patRecMag.restDistances = restDistances;
        end

        function mvc = CalculateMVC(app,dataBool)
            skipIdx = sum(app.magData.magSelFeatures);
            if dataBool
                dataAll = app.magData.dataTreated;
                mPairsChosen = app.patRecMag.magnetPairs;
                mPairsExtracted = app.magData.magnetPairs;
                idx = find(ismember(mPairsExtracted,mPairsChosen,'rows'));
                if (skipIdx == 2)
                    idx = [idx*2-1; idx*2];
                    idx = sort(idx);
                end
                data = cell(1,length(dataAll));
                for i = 1:length(data)
                    data{i} = dataAll{i}(:,idx);
                end
            else
                [files, path] = uigetfile('*.txt',"MultiSelect","on");
                mPairs = app.patRecMag.magnetPairs;
                if ~isequal(files, 0)
                    data = cell(1,length(files));
                    for i = 1:length(files)
                        temp = readmatrix([path, files{i}], 'DecimalSeparator','.');
                        if (sum(sum(isnan(temp(:,1:app.NumOfCollumsEditField.Value)))))
                            temp = readmatrix([path, files{i}], 'DecimalSeparator',',');
                        end
                        for j = 1:size(mPairs,1)
                            data{i} = [data{i}, app.patRecMag.restDistances(j*skipIdx - 1) - ...
                                vecnorm(temp(:,(1:3) + 6*(mPairs(j,1)-1)) - temp(:,(1:3) + 6*(mPairs(j,2)-1)),2,2)];
                        end
                    end
                end
                skipIdx = 1;
            end
            mvc  = zeros(app.patRecMag.nM, size(app.patRecMag.magnetPairs,1));
            mov = app.patRecMag.mov;
            nM = app.patRecMag.nM;
            if sum(contains(mov,'Rest'))
                nM = nM - 1;
            end
            for i = 1:nM
                mvc(i,:) = max(data{i}(:,1:skipIdx:end),[],1);
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadtxtfilesfromafolderButton
        function LoadtxtfilesfromafolderButtonPushed(app, event)
            app.MessageLabel.Text = 'Loading text file...';
            [files, path] = uigetfile('*.txt',"MultiSelect","on");
            if ~isequal(files, 0)
                for i = 1:length(app.MovementstobeloadedListBox.Value)
                    app.txtData{i} = readmatrix([path, files{i}], 'DecimalSeparator','.');
                    if (sum(sum(isnan(app.txtData{i}(:,1:app.NumOfCollumsEditField.Value)))))
                        app.txtData{i} = readmatrix([path, files{i}], 'DecimalSeparator',',');
                    end
                end
                if (length(files) ~= length(app.MovementstobeloadedListBox.Value)) % The number of files needs to match the movement names
                    app.MessageLabel.Text = 'Error: The movement names don''t match to the files!';
                    return
                end
                if (app.NumOfRepEditField.Value <= 0) % Number of repetitions needs to be a positive number
                    app.MessageLabel.Text = 'Error: The number of repetitions is not entered!';
                    return
                end
                if (app.ContractionTimeEditField.Value <= 0) % Contraction time needs to be a positive number
                    app.MessageLabel.Text = 'Error: The cotraction time is not entered!';
                    return
                end
                if (app.RestTimeEditField.Value <= 0) % Rest time needs to be a positive number
                    app.MessageLabel.Text = 'Error: The rest time is not entered!';
                    return
                end
                if (app.NumOfCollumsEditField.Value == 0) % Collumn numbers need to be entered
                    app.MessageLabel.Text = 'Error: The data collumns are not entered!';
                    return
                end
                if (app.LabelCollumnEditField.Value == 0) % Label collumn needs to be entered
                    app.MessageLabel.Text = 'Error: The label collums is not entered!';
                    return
                end
                CreateMagData(app); % creating a structure which contains all the info related to the data acquisition
                app.MessageLabel.Text = 'Files loaded.';
            else
                app.MessageLabel.Text = 'Error: Files not loaded!';
            end
        end

        % Menu selected function: SavemagDataMenu
        function SavemagDataMenuSelected(app, event)
                if ~isempty(app.magData)
                    [filename, pathname] = uiputfile({'*.mat','MAT-files (*.mat)'},'Save as', 'magData.mat');
                    magData = app.magData;
                    save([pathname,filename],'magData');
                    app.MessageLabel.Text = 'magData saved.';
                else
                    app.MessageLabel.Text = 'Error: No data to save!';
                end
        end

        % Button pushed function: LoadmagDataButton
        function LoadmagDataButtonPushed(app, event)
            app.MessageLabel.Text = 'Loading magData file...';
            [file, path] = uigetfile({'*.mat'});
            if ~isequal(file, 0)
                load([path,file]); % load patRec file 
                if exist('magData','var')
                    app.magData = magData;
                    app.MessageLabel.Text = 'magData loaded.';
                else
                    app.MessageLabel.Text = 'Error: Wrong file!';
                end
            else
                app.MessageLabel.Text = 'Error: No file selected!';
            end
        end

        % Button pushed function: DisplaymagDatainfoButton
        function DisplaymagDatainfoButtonPushed(app, event)
            if (~isempty(app.magData))
                app.NumberofrepetitionsEditField.Value = app.magData.nR;
                app.NumberofchannelsEditField.Value = app.magData.nCh;
                app.NumberofmovementsEditField.Value = app.magData.nM;
                app.ContractiontimeEditField.Value = app.magData.cT;
                app.ResttimeEditField.Value = app.magData.rT;
                app.NumberofmagnetsEditField.Value = app.magData.nMag;
                app.MovementsListBox.Items = app.magData.mov;
                app.MovementsListBox.Value = app.magData.mov;
                
            else
                app.MessageLabel.Text = 'Error: magData is empty!';
            end
        end

        % Button pushed function: ExctractFeaturesButton
        function ExctractFeaturesButtonPushed(app, event)
            % This function claculated and stores all selected features
            if ~isempty(app.magData)
                magFeaturesSelected = [app.LineardisplacementCheckBox.Value, app.AngulardisplacementCheckBox.Value];
                app.MessageLabel.Text = 'Extracting features...';
                drawnow;
                % Calculating the linar and angular distances for all magnet pairs
                [app.magData, message] = ExtractLinearAngularDistance(app.magData,magFeaturesSelected,app.UsedisplacementCheckBox.Value);
                if (CheckMessage(app,message))
                    return;
                end
                % Taking just a percentage of the contraction window
                [app.magData, message] = EffectiveContractionExtraction(app.magData,app.EffectivecontractioncTpEditField.Value);
                if (CheckMessage(app,message))
                    return;
                end
                % Seprating all the contraction intervals from the rest intervals
                [app.magData, message] = SeparateClassData(app.magData, app.AddRestasmovementCheckBox.Value);
                if (CheckMessage(app,message))
                    return;
                end
                if (app.UsejustsamplesCheckBox.Value) % in case this flag is set, the features are not extracted, instead raw samples are used
                    app.magData.exFeatures = app.magData.dataTreated;
                    app.magData.selFeatures = [];
                    app.MessageLabel.Text = 'Features extracted.';
                    return;
                end
                % Exctract features
                app.magData.tW = app.TimewindowmsEditField.Value*0.001;
                app.magData.wIncrement = app.WindowincremensmsEditField.Value*0.001;
                app.magData.sF = app.SamplefrequencyEditField.Value;
                if (app.magData.tW <= 0 || app.magData.wIncrement <= 0)
                    app.MessageLabel.Text = 'Error: time window and increment not properly set!';
                    return;
                end
                [app.magData, message] = ExtractFeatures(app.magData, app.FeaturesListBox.Value);
                if (CheckMessage(app,message))
                    return;
                end
                if (app.AddRestasmovementCheckBox.Value && app.magData.nM > length(app.magData.mov)) % add rest movement to the list of movements
                    app.magData.mov{end+1} = 'Rest';
                end
                % Display all the info in the training and testing tab
                app.AvailableMovementsListBox.Items = app.magData.mov;
                app.AvailableMovementsListBox.Value = app.magData.mov;
                app.ExtractedFeaturesListBox.Items = fieldnames(app.magData.exFeatures{1});
                app.ExtractedFeaturesListBox.Value = fieldnames(app.magData.exFeatures{1});
                app.AvailablemagnetpairsListBox.Items = cellstr(num2str(app.magData.magnetPairs));
                app.AvailablemagnetpairsListBox.Value = app.AvailablemagnetpairsListBox.Items;
                magnetFeatures = {};
                if (app.LineardisplacementCheckBox.Value)
                    magnetFeatures{end+1} = 'Linear dist.';
                end
                if (app.AngulardisplacementCheckBox.Value)
                    magnetFeatures{end+1} = 'Angular dist.';
                end
                app.AvailableMagnetFeaturesListBox.Items = magnetFeatures';
                app.AvailableMagnetFeaturesListBox.Value = magnetFeatures';
                % Display all available features in data visualization part
                app.FeaturesPlotListBox.Items = app.magData.selFeatures;
                app.FeaturesPlotListBox.Value = app.FeaturesPlotListBox.Items{1};
            else
                app.MessageLabel.Text = 'Error: No magData!';
            end
        end

        % Value changed function: MovementstobeloadedListBox
        function MovementstobeloadedListBoxValueChanged(app, event)
            value = app.MovementstobeloadedListBox.Value;
            if isempty(value)
                app.LoadtxtfilesfromafolderButton.Enable = "off";
            else
                app.LoadtxtfilesfromafolderButton.Enable = "on";
            end
        end

        % Button pushed function: LoadrestdataButton
        function LoadrestdataButtonPushed(app, event)
            app.MessageLabel.Text = 'Loading rest file...';
            [files, path] = uigetfile('*.txt');
            if ~isequal(files, 0)
                restData = readmatrix([path, files], 'DecimalSeparator','.');
                if (sum(sum(isnan(restData))))
                    restData= readmatrix([path, files], 'DecimalSeparator',',');
                end
                if (app.NumOfCollumsEditField.Value == 0) % Collumn numbers need to be entered
                    app.MessageLabel.Text = 'Error: The data collumns are not entered!';
                    return
                end
                app.magData.restData = restData(:,1:app.NumOfCollumsEditField.Value);
                app.MessageLabel.Text = 'File loaded.';
            else
                app.MessageLabel.Text = 'Error: File not loaded!';
            end
        end

        % Button pushed function: RunOfflineTrainingButton
        function RunOfflineTrainingButtonPushed(app, event)
            if (~isfield(app.magData,'exFeatures'))
                app.MessageLabel.Text = 'Error: Features are not extracted!';
                return;
            end
            app.MessageLabel.Text = 'Training the algorithm...';
            drawnow;
            app.patRecMag.mov = SortListBox(app,app.AvailableMovementsListBox);
            app.patRecMag.selFeatures = SortListBox(app,app.ExtractedFeaturesListBox);
            magPairsUsed = SortListBox(app,app.AvailablemagnetpairsListBox);
            app.patRecMag.magnetPairs = str2num(cell2mat(magPairsUsed'));
            app.patRecMag.magSelFeatures = [ismember('Linear dist.',app.AvailableMagnetFeaturesListBox.Value), ismember('Angular dist.',app.AvailableMagnetFeaturesListBox.Value)];
            app.patRecMag.algorithm.type = app.ClassifierDropDown.Value;
            app.patRecMag.algorithm.kernel = app.KernelDropDown.Value;
            app.patRecMag.algorithm.transient = app.TransientDataCheckBox.Value;
            app.patRecMag.normalization.type = app.NormalizationDropDown.Value;
            app.patRecMag.topology = app.TopologyDropDown.Value;
            app.patRecMag.trainingPercentage = app.TrainingDataPercentageEditField.Value/100;
            app.patRecMag.testingRep = app.NumRepTestEditField.Value;
            app.patRecMag.plotConfMat = app.PlotconfusionmatrixCheckBox.Value;
            app.patRecMag.plotFigures = app.PlotadditionalfiguresCheckBox.Value;
            app.patRecMag.randomize = app.RandomizedataCheckBox.Value;
            app.patRecMag.featSelection.algorithm = app.FeatureSelectionDropDown.Value;
            if (strcmp(app.patRecMag.featSelection.algorithm,'Correlation'))
                prompt = {'Enter minimum correlation with label', 'Enter maximum correlation within features'};
                dlgtitle = 'Correlation coefficients';
                dims = [1 35];
                definput = {'0.1','0.8'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                if isempty(answer)
                    return;
                end
                app.patRecMag.featSelection.scoreLabel = str2num(answer{1});
                app.patRecMag.featSelection.scoreFeat = str2num(answer{2});
            else
                app.patRecMag.featSelection.scorePercentage = app.PercentageofscoreEditField.Value;
            end
            if(strcmp(app.patRecMag.topology,'Bottleneck test'))
                app.patRecMag.algorithm.BottleneckNum = app.BottlenecktrialsEditField.Value;
            end

            [app.patRecMag, message] = OfflineTraining(app.magData, app.patRecMag);

            if (CheckMessage(app,message))
                return;
            end
            patRec = app.patRecMag;
            performance = patRec.performance;
            app.AverageaccuracyEditField.Value = patRec.performance.accuracy;
            app.PerformanceTable.RowName = patRec.mov';
            app.PerformanceTable.RowName(end+1) = {'Average'};
            avgPerformance = [mean(performance.classAccuracy), mean(performance.precision), mean(performance.f1)];
            app.PerformanceTable.Data = [performance.classAccuracy, performance.precision, performance.f1; avgPerformance];
        end

        % Value changed function: TopologyDropDown
        function TopologyDropDownValueChanged(app, event)
            value = app.TopologyDropDown.Value;
            if contains(value,'Single Classifier')
                app.TrainingDataPercentageEditField.Enable = "on";
                app.NumRepTestEditField.Enable = "off";
            elseif contains(value,'Cross-validation')
                app.TrainingDataPercentageEditField.Enable = "off";
                app.NumRepTestEditField.Enable = "on";
            elseif contains(value,'Bottleneck test')
                app.BottlenecktrialsEditField.Enable = "on";
                app.NumRepTestEditField.Enable = "on";
                app.PlotconfusionmatrixCheckBox.Value = 0;
                app.PlotadditionalfiguresCheckBox.Value = 1;
            elseif contains(value,'Train model')
                app.TrainingDataPercentageEditField.Enable = "on";
                app.NumRepTestEditField.Enable = "off";
                app.BottlenecktrialsEditField.Enable = "off";
            end
        end

        % Menu selected function: SavepatRecMenu
        function SavepatRecMenuSelected(app, event)
            if ~isempty(app.patRecMag)
                [filename, pathname] = uiputfile({'*.mat','MAT-files (*.mat)'},'Save as', 'patRec.mat');
                patRec = app.patRecMag;
                save([pathname,filename],'patRec');
                app.MessageLabel.Text = 'patRec saved.';
            else
                app.MessageLabel.Text = 'Error: No data to save!';
            end
        end

        % Button pushed function: CheckDataBalanceButton
        function CheckDataBalanceButtonPushed(app, event)
            if (isempty(app.magData) || ~isfield(app.magData,'exFeatures'))
                app.MessageLabel.Text = 'Error: no data available!';
                return;
            end
            if (isfield(app.magData,'exFeaturesTrans'))
                app.DataBalanceTable.ColumnName{2} = 'N.S. Trans';
            end
            data = app.magData.exFeatures;
            nM = app.magData.nM;
            dataLength = zeros(nM,1);
            for m = 1:nM
                dataLength(m) = length(data{m});
            end
            app.DataBalanceTable.RowName = app.magData.mov;
            app.DataBalanceTable.Data = dataLength;
            if (isfield(app.magData,'exFeaturesTrans'))
                data = app.magData.exFeaturesTrans;
                nM = size(data,2);
                dataLength = zeros(nM,1);
                for m = 1:nM
                    dataLength(m) = length(data{m});
                end
                dataLength(end+1) = 0;
                app.DataBalanceTable.Data(:,2) = dataLength;
            end
            app.MessageLabel.Text = 'Data samples displayed.';
        end

        % Button pushed function: BalancedataButton
        function BalancedataButtonPushed(app, event)
            if (isempty(app.magData) || ~isfield(app.magData,'exFeatures'))
                app.MessageLabel.Text = 'Error: no data available!';
                return;
            end
            data = app.magData.exFeatures;
            nM = app.magData.nM;
            dataLength = zeros(nM,1);
            for m = 1:nM
                dataLength(m) = length(data{m});
            end
            minLength = min(dataLength);
            dataBalanced = cell(1,nM);
            for m = 1:nM
                ind = randperm(dataLength(m));
                ind = ind(1:minLength);
                ind = sort(ind);
                dataBalanced{m} = data{m}(ind,:);
            end
            app.magData.exFeatures = dataBalanced;
            app.MessageLabel.Text = 'Data are balanced.';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if (isempty(app.magData) || ~isfield(app.magData,'dataLA'))
                app.MessageLabel.Text = 'Error: no data available!';
                return;
            end
            dataLA = app.magData.dataLA;
            nM = size(dataLA,2);
            % Plotting linear distance -----------------------------------
            if (app.LineardistPlotCheckBox.Value)
                if (app.magData.magSelFeatures(1))
                    if(app.magData.magSelFeatures(2))
                        ch = 1:2:(app.magData.nCh*2);
                    else
                        ch = 1:app.magData.nCh;
                    end
                    if (length(ch) > 7)
                        subplotCollumns = 2;
                        subplotRows = fix(length(ch)/2) + 1;
                    else
                        subplotCollumns = 1;
                        subplotRows = length(ch)+1;
                    end
                    for m = 1:nM
                        figure('Name',['Linear distance: ' app.magData.mov{m}])
%                         subplot(nM,1,m);
                        for c = 1:length(ch)
                            subplot(subplotRows,subplotCollumns,c);
                            plot(dataLA{m}(:,ch(c)).*1000)
                            title("Magnet pair (" + num2str(app.magData.magnetPairs(c,:)) + ")");
                        end
                        subplot(subplotRows,subplotCollumns,c+1);
                        plot(mean(dataLA{m}(:,ch),2).*1000)
                        title("Averaged over magnet pairs");
                    end
                else
                    app.MessageLabel.Text = 'Error: Linear distance not extracted!';
                    return;
                end
            end
            % Plotting angular distance -----------------------------------
            if (app.AngulardistPlotCheckBox.Value)
                if (app.magData.magSelFeatures(2))
                    if(app.magData.magSelFeatures(1))
                        ch = 2:2:(app.magData.nCh*2);
                    else
                        ch = 1:app.magData.nCh;
                    end
                    if (length(ch) > 7)
                        subplotCollumns = 2;
                        subplotRows = fix(length(ch)/2) + 1;
                    else
                        subplotCollumns = 1;
                        subplotRows = length(ch);
                    end
                    for m = 1:nM
                        figure('Name',['Angular distance: ' app.magData.mov{m}])
                        subplot(nM,1,m);
                        for c = 1:length(ch)
                            subplot(subplotRows,subplotCollumns,c);
                            plot(dataLA{m}(:,ch(c)))
                            title("Magnet pair (" + num2str(app.magData.magnetPairs(c,:)) + ")");
                        end
                    end
                else
                    app.MessageLabel.Text = 'Error: Angular distance not extracted!';
                    return;
                end
            end
            % Plotting features ------------------------------------------
%             if (~isempty(app.FeaturesPlotListBox.Value))
%                 if(isfield(app.magData,'exFeaturesTrans'))
%                     
%                 else
%                     app.MessageLabel.Text = 'Error: Trans features are not extracted!';
%                 end
%             end
            app.MessageLabel.Text = 'Data plotted.';
        end

        % Button pushed function: CheckchannelvarianceButton
        function CheckchannelvarianceButtonPushed(app, event)
            if (isempty(app.magData) || ~isfield(app.magData,'dataLA'))
                app.MessageLabel.Text = 'Error: no data available!';
                return;
            end
            nR = app.magData.nR;
            nRvar = app.NRVarianceEditField.Value;
            if (~nRvar) || (nRvar > nR)
                nRvar = nR;
            end
            chL = GetChannels(app,1);
            chA = GetChannels(app,0);
            dataLA = app.magData.dataLA;
            nM = size(dataLA,2);
            varL = zeros(nM,app.magData.nCh);
            varA = zeros(nM,app.magData.nCh);
            for m = 1:nM
                lengthRvar = round(length(dataLA{m})/nR*nRvar);
                varL(m,:) = var(dataLA{m}(1:lengthRvar,chL));
                varA(m,:) = var(dataLA{m}(1:lengthRvar,chA));
            end
            figure
            subplot(1,2,1)
            boxplot(varL);
            title('Linear distance channels')
            ylabel('Variance')
            xlabel('Magnet pair')
            subplot(1,2,2)
            boxplot(varA);
            title('Angular distance channels')
            ylabel('Variance')
            xlabel('Magnet pair')
        end

        % Value changed function: FeatureSelectionDropDown
        function FeatureSelectionDropDownValueChanged(app, event)
            value = app.FeatureSelectionDropDown.Value;
            if (contains(value,'Select'))
                app.PercentageofscoreEditField.Enable = 'off';
            else
                app.PercentageofscoreEditField.Enable = 'on';
            end
        end

        % Button pushed function: Plot3DButton
        function Plot3DButtonPushed(app, event)
            selFeatures = app.FeaturesPlotListBox.Value;
            featuresStruct = app.magData.exFeatures;
            nM = app.magData.nM;
            nCh = app.magData.nCh*sum(app.magData.magSelFeatures);
            dataLength = 0;
            for m = 1:nM
                dataLength = dataLength + length(featuresStruct{m});
            end

            dataSet = zeros(dataLength,nCh*length(selFeatures));
            dataOut = zeros(dataLength,1);
            dSIdx = 0; % index that indicated at which point to enter data in dataSet
            for m = 1:nM
                movementLength = length(featuresStruct{m});
                for s = 1:movementLength
                    for f = 1:length(selFeatures)
                        dataSet(dSIdx+s,(1:nCh) + (f-1)*nCh) = featuresStruct{m}(s).(selFeatures{f}); 
                    end
                end
                dataOut((dSIdx+1):(dSIdx + movementLength)) = m;
                dSIdx = dSIdx + movementLength;
            end
            switch app.AlgorithmforFRDropDown.Value
                case 'PCA'
                    coeff = pca(dataSet);
                    dataSet3D = dataSet*coeff(:,1:3);
                case 'TSNE'
                    dataSet3D = tsne(dataSet,'NumDimensions',3);
%                     dataSet3D = tsne(dataSet);
                otherwise
                    app.MessageLabel.Text = 'Wrong feature reduction algorithm';
                    return;
            end
            RGBvalues = linspace(0,0.9,nM)';
            Ridx = randperm(length(RGBvalues)); Gidx = randperm(length(RGBvalues)); Bidx = randperm(length(RGBvalues));
            C = [RGBvalues(Ridx),RGBvalues(Gidx),RGBvalues(Bidx)];
            figure
            for m = 1:nM
                hold on
                scatter3(dataSet3D(dataOut == m,1), dataSet3D(dataOut == m,2), dataSet3D(dataOut == m,3),36,C(m,:),'filled');
%                 scatter(dataSet3D(dataOut == m,1), dataSet3D(dataOut == m,2),36,C(m,:),'filled');
            end
            hold off
            grid on
            xlabel('1st Component') 
            ylabel('2nd Component')
            zlabel('3rd Component')
            legend(app.magData.mov)
%             legend('Pronation_1','Pronation_1_1','Pronation_2','Pronation_3','Supination_1','Supination_1_1','Supination_2','Supination_3','Rest_1','Rest_1_1','Rest_2','Rest_3')

%             save('DD11','dataSet','dataOut');
        end

        % Menu selected function: SavepatRectxtMenu
        function SavepatRectxtMenuSelected(app, event)
            if ~isempty(app.patRecMag)
                CalculateRestDistance(app);
                patRec = app.patRecMag;
                answer = questdlg('How do you want to calculate MVC?', ...
	                'MVC options', ...
	                'From a file','From data','Do not calculate','Do not calculate');
                switch answer
                    case 'Do not calculate'
                        mvc = [];
                    case 'From a file'
                        mvc = CalculateMVC(app,0);
                    case 'From data'
                        mvc = CalculateMVC(app,1);
                end
                [filename, pathname] = uiputfile({'*.txt'},'Save as', 'patRec.txt');
                % CLASSES -------------------------------------------------
                if strcmp(patRec.algorithm.type,'MLR')
                    tempCell = {'DOFs', patRec.nM};
                else
                    tempCell = {'Classes',patRec.nM};
                end
                writecell(tempCell,[pathname,filename],'Delimiter',',');
                writecell(patRec.mov,[pathname,filename],'WriteMode','append','Delimiter',',');
                % MAGNET PAIRS --------------------------------------------
                tempCell = {'Magnet pairs',size(patRec.magnetPairs,1)};
                writecell(tempCell,[pathname,filename],'WriteMode','append','Delimiter',',');
                writematrix(patRec.magnetPairs,[pathname,filename],'WriteMode','append','Delimiter',',');
                % MAGNET FEATURES -----------------------------------------
                tempCell = {'Magnet features',length(patRec.magSelFeatures)};
                writecell(tempCell,[pathname,filename],'WriteMode','append','Delimiter',',');
                writematrix(patRec.magSelFeatures,[pathname,filename],'WriteMode','append','Delimiter',',');
                % NORMALIZATION -------------------------------------------
                tempCell = {'Normalization',length(patRec.normalization.param1)};
                writecell(tempCell,[pathname,filename],'WriteMode','append','Delimiter',',');
                writematrix(patRec.normalization.param1,[pathname,filename],'WriteMode','append','Delimiter',',');
                writematrix(patRec.normalization.param2,[pathname,filename],'WriteMode','append','Delimiter',',');
                % CLASSIFICATION MODEL ------------------------------------
                tempCell = {'Model parameters'};
                writecell(tempCell,[pathname,filename],'WriteMode','append','Delimiter',',');
                if strcmp(patRec.algorithm.type,'MLR')
                    for m = 1:patRec.nM
                        MLRmodel = patRec.algorithm.model{1}.coeff(:,m);
                        tempCell = {'Model',m};
                        writecell(tempCell,[pathname,filename],'WriteMode','append','Delimiter',',');
                        writematrix(MLRmodel(2:end)',[pathname,filename],'WriteMode','append','Delimiter',',');
                        writematrix(MLRmodel(1),[pathname,filename],'WriteMode','append','Delimiter',',');                
                    end
                else
                    for m = 1:patRec.nM
                        SVMmodel = patRec.algorithm.model{m};
                        tempCell = {'Model',m};
                        writecell(tempCell,[pathname,filename],'WriteMode','append','Delimiter',',');
                        writematrix(SVMmodel.Beta',[pathname,filename],'WriteMode','append','Delimiter',',');
                        writematrix(SVMmodel.Bias,[pathname,filename],'WriteMode','append','Delimiter',',');                
                    end
                end
                % REST DISTANCES ------------------------------------------
                tempCell = {'Rest distances',length(patRec.restDistances)};
                writecell(tempCell,[pathname,filename],'WriteMode','append','Delimiter',',');
                writematrix(patRec.restDistances,[pathname,filename],'WriteMode','append','Delimiter',',');
                % MAXIMUM VOLUNTARY CONTRACTIONS -------------------------
                if ~isempty(mvc)
                    tempCell = {'MVC displacements',size(mvc,2)};
                    writecell(tempCell,[pathname,filename],'WriteMode','append','Delimiter',',');
                    writematrix(mvc,[pathname,filename],'WriteMode','append','Delimiter',',');
                end
                app.MessageLabel.Text = 'patRec saved.';
            else
                app.MessageLabel.Text = 'Error: No data to save!';
            end
        end

        % Menu selected function: LoadpatRecMenu
        function LoadpatRecMenuSelected(app, event)
            app.MessageLabel.Text = 'Loading patRec file...';
            drawnow;
            [file, path] = uigetfile({'*.mat'});
            if ~isequal(file, 0)
                load([path,file]); % load patRec file 
                if exist('patRec','var')
                    app.patRecMag = patRec;
                    app.MessageLabel.Text = 'patRec loaded.';

                    app.AvailableMovementsListBox.Items = app.patRecMag.mov;
                    app.AvailableMovementsListBox.Value = app.patRecMag.mov;
                    app.ExtractedFeaturesListBox.Items = app.patRecMag.selFeatures;
                    app.ExtractedFeaturesListBox.Value = app.patRecMag.selFeatures;
                    app.AvailablemagnetpairsListBox.Items = cellstr(num2str(app.patRecMag.magnetPairs));
                    app.AvailablemagnetpairsListBox.Value = cellstr(num2str(app.patRecMag.magnetPairs));
                    app.AvailableMagnetFeaturesListBox.Items = {'Linear dist.','Angular dist.'};
                    app.AvailableMagnetFeaturesListBox.Value = {};
                    if (app.patRecMag.magSelFeatures(1))
                        app.AvailableMagnetFeaturesListBox.Value(end+1) = app.AvailableMagnetFeaturesListBox.Items(1);
                    end
                    if (app.patRecMag.magSelFeatures(2))
                        app.AvailableMagnetFeaturesListBox.Value(end+1) = app.AvailableMagnetFeaturesListBox.Items(2);
                    end
                    app.ClassifierDropDown.Value = app.patRecMag.algorithm.type;
                    app.KernelDropDown.Value = app.patRecMag.algorithm.kernel;
                    app.TransientDataCheckBox.Value = app.patRecMag.algorithm.transient;
                    app.NormalizationDropDown.Value = app.patRecMag.normalization.type;
                    app.TopologyDropDown.Value = {'Test model'};
                    app.TrainingDataPercentageEditField.Value = app.patRecMag.trainingPercentage*100;
                    app.NumRepTestEditField.Value = app.patRecMag.testingRep;
                    app.FeatureSelectionDropDown.Value = app.patRecMag.featSelection.algorithm;
                    app.PercentageofscoreEditField.Value = app.patRecMag.featSelection.scorePercentage;
                    if(strcmp(app.patRecMag.topology,'Bottleneck test'))
                        app.BottlenecktrialsEditField.Value = app.patRecMag.algorithm.BottleneckNum;
                    end
                else
                    app.MessageLabel.Text = 'Error: Wrong file!';
                end
            else
                app.MessageLabel.Text = 'Error: No file selected!';
            end
        end

        % Value changed function: ClassifierDropDown
        function ClassifierDropDownValueChanged(app, event)
            value = app.ClassifierDropDown.Value;
            if strcmp(value,'MLR')
                app.TopologyDropDown.Value = "Single Classifier";
                app.TopologyDropDown.Enable = "off";
                app.TrainingDataPercentageEditField.Enable = "on";
            else
                app.TopologyDropDown.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.SavemagDataMenu = uimenu(app.DataMenu);
            app.SavemagDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavemagDataMenuSelected, true);
            app.SavemagDataMenu.Text = 'Save magData';
            app.SavepatRecMenu = uimenu(app.DataMenu);
            app.SavepatRecMenu.MenuSelectedFcn = createCallbackFcn(app, @SavepatRecMenuSelected, true);
            app.SavepatRecMenu.Text = 'Save patRec';
            app.SavepatRectxtMenu = uimenu(app.DataMenu);
            app.SavepatRectxtMenu.MenuSelectedFcn = createCallbackFcn(app, @SavepatRectxtMenuSelected, true);
            app.SavepatRectxtMenu.Text = 'Save patRec.txt';
            app.LoadpatRecMenu = uimenu(app.DataMenu);
            app.LoadpatRecMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadpatRecMenuSelected, true);
            app.LoadpatRecMenu.Text = 'Load patRec';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrainingandtestingTab = uitab(app.TabGroup);
            app.TrainingandtestingTab.Title = 'Training and testing';
            app.AvailablemovementsandextractedfeaturesPanel = uipanel(app.TrainingandtestingTab);
            app.AvailablemovementsandextractedfeaturesPanel.Title = 'Available movements and extracted features';
            app.ExtractedFeaturesLabel = uilabel(app.AvailablemovementsandextractedfeaturesPanel);
            app.ExtractedFeaturesLabel.Text = 'ExtractedFeatures';
            app.ExtractedFeaturesListBox = uilistbox(app.AvailablemovementsandextractedfeaturesPanel);
            app.ExtractedFeaturesListBox.Items = {};
            app.ExtractedFeaturesListBox.Multiselect = 'on';
            app.ExtractedFeaturesListBox.Value = {};
            app.AvailablemovementsLabel = uilabel(app.AvailablemovementsandextractedfeaturesPanel);
            app.AvailablemovementsLabel.Text = 'Available Movements ';
            app.AvailableMovementsListBox = uilistbox(app.AvailablemovementsandextractedfeaturesPanel);
            app.AvailableMovementsListBox.Items = {};
            app.AvailableMovementsListBox.Multiselect = 'on';
            app.AvailableMovementsListBox.Value = {};
            app.AvailablemagnetpairsLabel = uilabel(app.AvailablemovementsandextractedfeaturesPanel);
            app.AvailablemagnetpairsLabel.Text = 'Available magnet pairs ';
            app.AvailablemagnetpairsListBox = uilistbox(app.AvailablemovementsandextractedfeaturesPanel);
            app.AvailablemagnetpairsListBox.Items = {};
            app.AvailablemagnetpairsListBox.Multiselect = 'on';
            app.AvailablemagnetpairsListBox.Value = {};
            app.AvailableMagnetFeaturesListBoxLabel = uilabel(app.AvailablemovementsandextractedfeaturesPanel);
            app.AvailableMagnetFeaturesListBoxLabel.Text = 'Available Magnet Features ';
            app.AvailableMagnetFeaturesListBox = uilistbox(app.AvailablemovementsandextractedfeaturesPanel);
            app.AvailableMagnetFeaturesListBox.Items = {};
            app.AvailableMagnetFeaturesListBox.Multiselect = 'on';
            app.AvailableMagnetFeaturesListBox.Value = {};
            app.RunOfflineTrainingButton = uibutton(app.TrainingandtestingTab, 'push');
            app.RunOfflineTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @RunOfflineTrainingButtonPushed, true);
            app.RunOfflineTrainingButton.Text = 'Run Offline Training';
            app.ClassifierparametersPanel = uipanel(app.TrainingandtestingTab);
            app.ClassifierparametersPanel.Title = 'Classifier parameters';
            app.ClassifierDropDownLabel = uilabel(app.ClassifierparametersPanel);
            app.ClassifierDropDownLabel.Text = 'Classifier';
            app.ClassifierDropDown = uidropdown(app.ClassifierparametersPanel);
            app.ClassifierDropDown.Items = {'Select Algorithm', 'SVM', 'MLR'};
            app.ClassifierDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassifierDropDownValueChanged, true);
            app.ClassifierDropDown.Value = 'Select Algorithm';
            app.KernelDropDownLabel = uilabel(app.ClassifierparametersPanel);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.ClassifierparametersPanel);
            app.KernelDropDown.Items = {'Select Kernel', 'Linear', 'Polynomial', 'Gaussian'};
            app.KernelDropDown.Value = 'Select Kernel';
            app.NormalizationDropDownLabel = uilabel(app.ClassifierparametersPanel);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.ClassifierparametersPanel);
            app.NormalizationDropDown.Items = {'Select Normalization', 'Midrange0Range2', 'Mean0Std1'};
            app.NormalizationDropDown.Value = 'Select Normalization';
            app.TopologyDropDownLabel = uilabel(app.ClassifierparametersPanel);
            app.TopologyDropDownLabel.Text = 'Topology';
            app.TopologyDropDown = uidropdown(app.ClassifierparametersPanel);
            app.TopologyDropDown.Items = {'Select Topology', 'Single Classifier', 'Single Classifier - test full', 'Cross-validation', 'Cross-validation - test full', 'Bottleneck test', 'Train model', 'Test model'};
            app.TopologyDropDown.ValueChangedFcn = createCallbackFcn(app, @TopologyDropDownValueChanged, true);
            app.TopologyDropDown.Value = 'Select Topology';
            app.PercentageofdatatobeusedfortrainingEditFieldLabel = uilabel(app.ClassifierparametersPanel);
            app.PercentageofdatatobeusedfortrainingEditFieldLabel.Text = 'Percentage of data to be used for training';
            app.TrainingDataPercentageEditField = uieditfield(app.ClassifierparametersPanel, 'numeric');
            app.TrainingDataPercentageEditField.Enable = 'off';
            app.TrainingDataPercentageEditField.Value = 70;
            app.RepetitionsusedfortestingLabel = uilabel(app.ClassifierparametersPanel);
            app.RepetitionsusedfortestingLabel.Text = 'Repetitions used for testing';
            app.NumRepTestEditField = uieditfield(app.ClassifierparametersPanel, 'numeric');
            app.NumRepTestEditField.Enable = 'off';
            app.NumRepTestEditField.Value = 5;
            app.PlotconfusionmatrixCheckBox = uicheckbox(app.ClassifierparametersPanel);
            app.PlotconfusionmatrixCheckBox.Text = 'Plot confusion matrix';
            app.TransientDataCheckBox = uicheckbox(app.ClassifierparametersPanel);
            app.TransientDataCheckBox.Text = 'Transient Data';
            app.PlotadditionalfiguresCheckBox = uicheckbox(app.ClassifierparametersPanel);
            app.PlotadditionalfiguresCheckBox.Text = 'Plot additional figures';
            app.RandomizedataCheckBox = uicheckbox(app.ClassifierparametersPanel);
            app.RandomizedataCheckBox.Text = 'Randomize data';
            app.FeatureSelectionDropDownLabel = uilabel(app.ClassifierparametersPanel);
            app.FeatureSelectionDropDownLabel.Text = 'Feature Selection';
            app.FeatureSelectionDropDown = uidropdown(app.ClassifierparametersPanel);
            app.FeatureSelectionDropDown.Items = {'Select Feature Selection', 'MRMR', 'Chi-square', 'PCA', 'Correlation'};
            app.FeatureSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureSelectionDropDownValueChanged, true);
            app.FeatureSelectionDropDown.Value = 'Select Feature Selection';
            app.PercentageofscoreEditFieldLabel = uilabel(app.ClassifierparametersPanel);
            app.PercentageofscoreEditFieldLabel.Text = 'Percentage of score';
            app.PercentageofscoreEditField = uieditfield(app.ClassifierparametersPanel, 'numeric');
            app.PercentageofscoreEditField.Enable = 'off';
            app.PercentageofscoreEditField.Value = 90;
            app.BottlenecktrialsEditFieldLabel = uilabel(app.ClassifierparametersPanel);
            app.BottlenecktrialsEditFieldLabel.Text = 'Bottleneck trials';
            app.BottlenecktrialsEditField = uieditfield(app.ClassifierparametersPanel, 'numeric');
            app.BottlenecktrialsEditField.Enable = 'off';
            app.BottlenecktrialsEditField.Value = 20;
            app.ClassifierperformancePanel = uipanel(app.TrainingandtestingTab);
            app.ClassifierperformancePanel.Title = 'Classifier performance';
            app.PerformanceTable = uitable(app.ClassifierperformancePanel);
            app.PerformanceTable.ColumnName = {'Class Accuracy'; 'Precision'; 'F1 score'};
            app.PerformanceTable.RowName = {};
            app.AverageaccuracyLabel = uilabel(app.ClassifierperformancePanel);
            app.AverageaccuracyLabel.Text = 'Average accuracy (%)';
            app.AverageaccuracyEditField = uieditfield(app.ClassifierperformancePanel, 'numeric');
            app.AverageaccuracyEditField.Editable = 'off';
            app.CreatingdatastructureTab = uitab(app.TabGroup);
            app.CreatingdatastructureTab.Title = 'Creating data structure';
            app.InformationaboutthefilePanel = uipanel(app.CreatingdatastructureTab);
            app.InformationaboutthefilePanel.Title = 'Information about the file';
            app.NumberofcollumnswhichcontaindataEditFieldLabel = uilabel(app.InformationaboutthefilePanel);
            app.NumberofcollumnswhichcontaindataEditFieldLabel.Text = 'Number of collumns which contain data';
            app.NumOfCollumsEditField = uieditfield(app.InformationaboutthefilePanel, 'numeric');
            app.NumOfCollumsEditField.Value = 36;
            app.NumberofrepetitionsEditFieldLabel = uilabel(app.InformationaboutthefilePanel);
            app.NumberofrepetitionsEditFieldLabel.Text = 'Number of repetitions';
            app.NumOfRepEditField = uieditfield(app.InformationaboutthefilePanel, 'numeric');
            app.NumOfRepEditField.Value = 15;
            app.DateofacquisitionEditFieldLabel = uilabel(app.InformationaboutthefilePanel);
            app.DateofacquisitionEditFieldLabel.Text = 'Date of acquisition';
            app.DateofacquisitionEditField = uieditfield(app.InformationaboutthefilePanel, 'text');
            app.ContractiontimeLabel = uilabel(app.InformationaboutthefilePanel);
            app.ContractiontimeLabel.Text = 'Contraction time';
            app.ContractionTimeEditField = uieditfield(app.InformationaboutthefilePanel, 'numeric');
            app.ContractionTimeEditField.Value = 5;
            app.ContractiontimeLabel_2 = uilabel(app.InformationaboutthefilePanel);
            app.ContractiontimeLabel_2.Text = 'Rest time';
            app.RestTimeEditField = uieditfield(app.InformationaboutthefilePanel, 'numeric');
            app.RestTimeEditField.Value = 5;
            app.CollumnwhichcontainslabelLabel = uilabel(app.InformationaboutthefilePanel);
            app.CollumnwhichcontainslabelLabel.Text = 'Collumn which contains label';
            app.LabelCollumnEditField = uieditfield(app.InformationaboutthefilePanel, 'numeric');
            app.LabelCollumnEditField.Value = 37;
            app.LoadtxtfilesfromafolderButton = uibutton(app.InformationaboutthefilePanel, 'push');
            app.LoadtxtfilesfromafolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtxtfilesfromafolderButtonPushed, true);
            app.LoadtxtfilesfromafolderButton.Enable = 'off';
            app.LoadtxtfilesfromafolderButton.Text = 'Load .txt files from a folder';
            app.NumbermagnetsLabel = uilabel(app.InformationaboutthefilePanel);
            app.NumbermagnetsLabel.Text = 'Number of magnets';
            app.NumOfMagnetsEditField = uieditfield(app.InformationaboutthefilePanel, 'numeric');
            app.NumOfMagnetsEditField.Value = 6;
            app.MovementstobeloadedListBoxLabel = uilabel(app.InformationaboutthefilePanel);
            app.MovementstobeloadedListBoxLabel.Text = 'Movements to be loaded';
            app.MovementstobeloadedListBox = uilistbox(app.InformationaboutthefilePanel);
            app.MovementstobeloadedListBox.Items = {'Close hand', 'Lateral grasp', 'Bidigital grasp', 'Open hand', 'Flex wrist', 'Extend wrist', 'Pronation', 'Supination', 'Io', 'Tu', 'Index flexion', 'Thumb flexion', 'Thumb opposition', 'Middle flexion', 'Little flexion', 'Sberla', 'Rest'};
            app.MovementstobeloadedListBox.Multiselect = 'on';
            app.MovementstobeloadedListBox.ValueChangedFcn = createCallbackFcn(app, @MovementstobeloadedListBoxValueChanged, true);
            app.MovementstobeloadedListBox.Value = {};
            app.LoadrestdataButton = uibutton(app.InformationaboutthefilePanel, 'push');
            app.LoadrestdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrestdataButtonPushed, true);
            app.LoadrestdataButton.Text = 'Load rest data';
            app.ExtractingFeaturesTab = uitab(app.TabGroup);
            app.ExtractingFeaturesTab.Title = 'Extracting Features';
            app.LoadmagDataButton = uibutton(app.ExtractingFeaturesTab, 'push');
            app.LoadmagDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmagDataButtonPushed, true);
            app.LoadmagDataButton.Text = 'Load magData';
            app.MagnetdatainfoPanel = uipanel(app.ExtractingFeaturesTab);
            app.MagnetdatainfoPanel.Title = 'Magnet data info';
            app.DisplaymagDatainfoButton = uibutton(app.MagnetdatainfoPanel, 'push');
            app.DisplaymagDatainfoButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaymagDatainfoButtonPushed, true);
            app.DisplaymagDatainfoButton.Text = 'Display magData info';
            app.NumberofrepetitionsEditFieldLabel_2 = uilabel(app.MagnetdatainfoPanel);
            app.NumberofrepetitionsEditFieldLabel_2.Text = 'Number of repetitions';
            app.NumberofrepetitionsEditField = uieditfield(app.MagnetdatainfoPanel, 'numeric');
            app.NumberofrepetitionsEditField.Editable = 'off';
            app.NumberofchannelsEditFieldLabel = uilabel(app.MagnetdatainfoPanel);
            app.NumberofchannelsEditFieldLabel.Text = 'Number of channels';
            app.NumberofchannelsEditField = uieditfield(app.MagnetdatainfoPanel, 'numeric');
            app.NumberofchannelsEditField.Editable = 'off';
            app.NumberofmovementsEditFieldLabel = uilabel(app.MagnetdatainfoPanel);
            app.NumberofmovementsEditFieldLabel.Text = 'Number of movements';
            app.NumberofmovementsEditField = uieditfield(app.MagnetdatainfoPanel, 'numeric');
            app.NumberofmovementsEditField.Editable = 'off';
            app.ContractiontimeEditFieldLabel = uilabel(app.MagnetdatainfoPanel);
            app.ContractiontimeEditFieldLabel.Text = 'Contraction time';
            app.ContractiontimeEditField = uieditfield(app.MagnetdatainfoPanel, 'numeric');
            app.ContractiontimeEditField.Editable = 'off';
            app.ResttimeEditFieldLabel = uilabel(app.MagnetdatainfoPanel);
            app.ResttimeEditFieldLabel.Text = 'Rest time';
            app.ResttimeEditField = uieditfield(app.MagnetdatainfoPanel, 'numeric');
            app.ResttimeEditField.Editable = 'off';
            app.NumberofmagnetsEditFieldLabel = uilabel(app.MagnetdatainfoPanel);
            app.NumberofmagnetsEditFieldLabel.Text = 'Number of magnets';
            app.NumberofmagnetsEditField = uieditfield(app.MagnetdatainfoPanel, 'numeric');
            app.NumberofmagnetsEditField.Editable = 'off';
            app.MovementsListBoxLabel = uilabel(app.MagnetdatainfoPanel);
            app.MovementsListBoxLabel.Text = 'Movements';
            app.MovementsListBox = uilistbox(app.MagnetdatainfoPanel);
            app.MovementsListBox.Items = {};
            app.MovementsListBox.Multiselect = 'on';
            app.MovementsListBox.Value = {};
            app.IncasemagDataisalreadysavedLabel = uilabel(app.ExtractingFeaturesTab);
            app.IncasemagDataisalreadysavedLabel.Text = 'In case magData is already saved:';
            app.MagnetfeaturesPanel = uipanel(app.ExtractingFeaturesTab);
            app.MagnetfeaturesPanel.Title = 'Magnet features';
            app.LineardisplacementCheckBox = uicheckbox(app.MagnetfeaturesPanel);
            app.LineardisplacementCheckBox.Text = 'Linear displacement';
            app.LineardisplacementCheckBox.Value = true;
            app.AngulardisplacementCheckBox = uicheckbox(app.MagnetfeaturesPanel);
            app.AngulardisplacementCheckBox.Text = 'Angular displacement';
            app.AngulardisplacementCheckBox.Value = true;
            app.FeaturesListBoxLabel = uilabel(app.MagnetfeaturesPanel);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.MagnetfeaturesPanel);
            app.FeaturesListBox.Items = {'tmabs', 'tmabsTr', 'tmd', 'tmax', 'tmin', 'twl', 'mlv', 'mslp'};
            app.FeaturesListBox.Multiselect = 'on';
            app.FeaturesListBox.Value = {'tmabs', 'tmd', 'tmax', 'tmin', 'twl', 'mlv', 'mslp'};
            app.AddRestasmovementCheckBox = uicheckbox(app.MagnetfeaturesPanel);
            app.AddRestasmovementCheckBox.Text = 'Add "Rest" as movement';
            app.AddRestasmovementCheckBox.Value = true;
            app.UsejustsamplesCheckBox = uicheckbox(app.MagnetfeaturesPanel);
            app.UsejustsamplesCheckBox.Text = 'Use just samples';
            app.SamplefrequencyEditFieldLabel = uilabel(app.MagnetfeaturesPanel);
            app.SamplefrequencyEditFieldLabel.Text = 'Sample frequency';
            app.SamplefrequencyEditField = uieditfield(app.MagnetfeaturesPanel, 'numeric');
            app.TimewindowmsEditFieldLabel = uilabel(app.MagnetfeaturesPanel);
            app.TimewindowmsEditFieldLabel.Text = 'Time window (ms)';
            app.TimewindowmsEditField = uieditfield(app.MagnetfeaturesPanel, 'numeric');
            app.TimewindowmsEditField.Value = 200;
            app.WindowincremensmsEditFieldLabel = uilabel(app.MagnetfeaturesPanel);
            app.WindowincremensmsEditFieldLabel.Text = 'Window incremens (ms)';
            app.WindowincremensmsEditField = uieditfield(app.MagnetfeaturesPanel, 'numeric');
            app.WindowincremensmsEditField.Value = 50;
            app.EffectivecontractioncTpEditFieldLabel = uilabel(app.MagnetfeaturesPanel);
            app.EffectivecontractioncTpEditFieldLabel.Text = 'Effective contraction (cTp)';
            app.EffectivecontractioncTpEditField = uieditfield(app.MagnetfeaturesPanel, 'numeric');
            app.EffectivecontractioncTpEditField.Value = 0.5;
            app.ExctractFeaturesButton = uibutton(app.MagnetfeaturesPanel, 'push');
            app.ExctractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExctractFeaturesButtonPushed, true);
            app.ExctractFeaturesButton.Text = 'Exctract Features';
            app.UsedisplacementCheckBox = uicheckbox(app.MagnetfeaturesPanel);
            app.UsedisplacementCheckBox.Text = 'Use displacement';
            app.DataVisualizationTab = uitab(app.TabGroup);
            app.DataVisualizationTab.Title = 'Data Visualization';
            app.DatabalancePanel = uipanel(app.DataVisualizationTab);
            app.DatabalancePanel.Title = 'Data balance';
            app.CheckDataBalanceButton = uibutton(app.DatabalancePanel, 'push');
            app.CheckDataBalanceButton.ButtonPushedFcn = createCallbackFcn(app, @CheckDataBalanceButtonPushed, true);
            app.CheckDataBalanceButton.Text = 'Check Data Balance';
            app.DataBalanceTable = uitable(app.DatabalancePanel);
            app.DataBalanceTable.ColumnName = {'Number of samples'};
            app.DataBalanceTable.RowName = {};
            app.BalancedataButton = uibutton(app.DatabalancePanel, 'push');
            app.BalancedataButton.ButtonPushedFcn = createCallbackFcn(app, @BalancedataButtonPushed, true);
            app.BalancedataButton.Text = 'Balance data';
            app.DataFeatureVisualizationPanel = uipanel(app.DataVisualizationTab);
            app.DataFeatureVisualizationPanel.Title = 'Data Feature Visualization';
            app.PlotButton = uibutton(app.DataFeatureVisualizationPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LineardistPlotCheckBox = uicheckbox(app.DataFeatureVisualizationPanel);
            app.LineardistPlotCheckBox.Text = 'Linear distance';
            app.AngulardistPlotCheckBox = uicheckbox(app.DataFeatureVisualizationPanel);
            app.AngulardistPlotCheckBox.Text = 'Angular distance';
            app.FeaturesListBox_2Label = uilabel(app.DataFeatureVisualizationPanel);
            app.FeaturesListBox_2Label.Text = 'Features';
            app.FeaturesPlotListBox = uilistbox(app.DataFeatureVisualizationPanel);
            app.FeaturesPlotListBox.Items = {'tmabs', 'tmabsTr', 'tmd', 'tmax', 'tmin', 'twl', 'mlv', 'mslp'};
            app.FeaturesPlotListBox.Multiselect = 'on';
            app.FeaturesPlotListBox.Value = {'tmabs'};
            app.CheckchannelvarianceButton = uibutton(app.DataFeatureVisualizationPanel, 'push');
            app.CheckchannelvarianceButton.ButtonPushedFcn = createCallbackFcn(app, @CheckchannelvarianceButtonPushed, true);
            app.CheckchannelvarianceButton.Text = 'Check channel variance';
            app.NumberofrepetitionsEditField_2Label = uilabel(app.DataFeatureVisualizationPanel);
            app.NumberofrepetitionsEditField_2Label.Text = 'Number of repetitions';
            app.NRVarianceEditField = uieditfield(app.DataFeatureVisualizationPanel, 'numeric');
            app.Plot3DButton = uibutton(app.DataFeatureVisualizationPanel, 'push');
            app.Plot3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DButtonPushed, true);
            app.Plot3DButton.Text = 'Plot 3D';
            app.AlgorithmforFRDropDownLabel = uilabel(app.DataFeatureVisualizationPanel);
            app.AlgorithmforFRDropDownLabel.Text = 'Algorithm for FR';
            app.AlgorithmforFRDropDown = uidropdown(app.DataFeatureVisualizationPanel);
            app.AlgorithmforFRDropDown.Items = {'PCA', 'TSNE'};
            app.AlgorithmforFRDropDown.Value = 'PCA';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Welcome';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagnetPatternRecognitionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end